classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResultLabel                   matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        YourphoneCategoryisLabel      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        GLabel_2                      matlab.ui.control.Label
        CheckBox_wifi                 matlab.ui.control.CheckBox
        CheckBox_touchScreen          matlab.ui.control.CheckBox
        WifiLabel                     matlab.ui.control.Label
        TouchScreenLabel              matlab.ui.control.Label
        CheckBox_3g                   matlab.ui.control.CheckBox
        CheckBox_4g                   matlab.ui.control.CheckBox
        DualSimLabel                  matlab.ui.control.Label
        CheckBox_dualsim              matlab.ui.control.CheckBox
        GLabel                        matlab.ui.control.Label
        CheckBox_blue                 matlab.ui.control.CheckBox
        BlueLabel                     matlab.ui.control.Label
        TalkTimeEditField             matlab.ui.control.NumericEditField
        TalkTimeEditFieldLabel        matlab.ui.control.Label
        ScreenWidthEditField          matlab.ui.control.NumericEditField
        ScreenWidthEditFieldLabel     matlab.ui.control.Label
        ScreenHeightEditField         matlab.ui.control.NumericEditField
        ScreenHeightEditFieldLabel    matlab.ui.control.Label
        RamEditField                  matlab.ui.control.NumericEditField
        RamEditFieldLabel             matlab.ui.control.Label
        WidthpxEditField              matlab.ui.control.NumericEditField
        WidthpxEditFieldLabel         matlab.ui.control.Label
        HeightpxEditField             matlab.ui.control.NumericEditField
        HeightpxEditFieldLabel        matlab.ui.control.Label
        PCEditField                   matlab.ui.control.NumericEditField
        PCEditFieldLabel              matlab.ui.control.Label
        CoresEditField                matlab.ui.control.NumericEditField
        CoresEditFieldLabel           matlab.ui.control.Label
        MobilewtEditField             matlab.ui.control.NumericEditField
        MobilewtEditFieldLabel        matlab.ui.control.Label
        MDepEditField                 matlab.ui.control.NumericEditField
        MDepEditFieldLabel            matlab.ui.control.Label
        InternalMemoryEditField       matlab.ui.control.NumericEditField
        InternalMemoryEditFieldLabel  matlab.ui.control.Label
        FcEditField                   matlab.ui.control.NumericEditField
        FcLabel                       matlab.ui.control.Label
        ClockSpeedEditField           matlab.ui.control.NumericEditField
        ClockSpeedEditFieldLabel      matlab.ui.control.Label
        BatteryPowerEditField         matlab.ui.control.NumericEditField
        BatteryPowerEditFieldLabel    matlab.ui.control.Label
        TestYourPhoneLabel            matlab.ui.control.Label
    end


    methods (Access = public)

            function results = func2(app)
          %app.ResultLabel.Text = int2str(app.CheckBox_3g.Value)
            pil=app.BatteryPowerEditField.Value
            blue=app.CheckBox_blue.Value
            clockSpeed=app.ClockSpeedEditField.Value
            dualSim=app.CheckBox_dualsim.Value
            fc=app.FcEditField.Value
            fourG=app.CheckBox_4g.Value
            intMemory=app.InternalMemoryEditField.Value
            mDep=app.MDepEditField.Value
            mobileWt=app.MobilewtEditField.Value
            cores=app.CoresEditField.Value
            pc=app.PCEditField.Value
            heightPx=app.HeightpxEditField.Value
            widthPx=app.WidthpxEditField.Value
            ram=app.RamEditField.Value
            screenH=app.ScreenHeightEditField.Value
            screenW=app.ScreenWidthEditField.Value
            talkTime=app.TalkTimeEditField.Value
            threeG=app.CheckBox_3g.Value
            touchScreen=app.CheckBox_touchScreen.Value
            wifi=app.CheckBox_wifi.Value


datasets=table([pil,blue;clockSpeed;dualSim;fc;fourG;intMemory;mDep;mobileWt;cores;pc;heightPx;widthPx;ram;screenH;screenW;talkTime;threeG;touchScreen;wifi])
%datasets=array2table(datasets)


        end
   
        
    function [trainedClassifier, validationAccuracy] = trainClassifier(trainingData)
% [trainedClassifier, validationAccuracy] = trainClassifier(trainingData)
% Returns a trained classifier and its accuracy. This code recreates the
% classification model trained in Classification Learner app. Use the
% generated code to automate training the same model with new data, or to
% learn how to programmatically train models.
%
%  Input:
%      trainingData: A table containing the same predictor and response
%       columns as those imported into the app.
%
%  Output:
%      trainedClassifier: A struct containing the trained classifier. The
%       struct contains various fields with information about the trained
%       classifier.
%
%      trainedClassifier.predictFcn: A function to make predictions on new
%       data.
%
%      validationAccuracy: A double containing the accuracy as a
%       percentage. In the app, the Models pane displays this overall
%       accuracy score for each model.
%
% Use the code to train the model with new data. To retrain your
% classifier, call the function from the command line with your original
% data or new data as the input argument trainingData.
%
% For example, to retrain a classifier trained with the original data set
% T, enter:
%   [trainedClassifier, validationAccuracy] = trainClassifier(T)
%
% To make predictions with the returned 'trainedClassifier' on new data T2,
% use
%   yfit = trainedClassifier.predictFcn(T2)
%
% T2 must be a table containing at least the same predictor columns as used
% during training. For details, enter:
%   trainedClassifier.HowToPredict

% Auto-generated by MATLAB on 15-Nov-2022 15:42:00


% Extract predictors and response
% This code processes the data into the right shape for training the
% model.
inputTable = trainingData;
predictorNames = {'battery_power', 'blue', 'clock_speed', 'dual_sim', 'fc', 'four_g', 'int_memory', 'm_dep', 'mobile_wt', 'n_cores', 'pc', 'px_height', 'px_width', 'ram', 'sc_h', 'sc_w', 'talk_time', 'three_g', 'touch_screen', 'wifi'};
predictors = inputTable(:, predictorNames);
response = inputTable.price_range;
isCategoricalPredictor = [false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false];

% Train a classifier
% This code specifies all the classifier options and trains the classifier.
template = templateSVM(...
    'KernelFunction', 'linear', ...
    'PolynomialOrder', [], ...
    'KernelScale', 'auto', ...
    'BoxConstraint', 1, ...
    'Standardize', true);
classificationSVM = fitcecoc(...
    predictors, ...
    response, ...
    'Learners', template, ...
    'Coding', 'onevsone', ...
    'ClassNames', [0; 1; 2; 3]);

% Create the result struct with predict function
predictorExtractionFcn = @(t) t(:, predictorNames);
svmPredictFcn = @(x) predict(classificationSVM, x);
trainedClassifier.predictFcn = @(x) svmPredictFcn(predictorExtractionFcn(x));

% Add additional fields to the result struct
trainedClassifier.RequiredVariables = {'battery_power', 'blue', 'clock_speed', 'dual_sim', 'fc', 'four_g', 'int_memory', 'm_dep', 'mobile_wt', 'n_cores', 'pc', 'px_height', 'px_width', 'ram', 'sc_h', 'sc_w', 'talk_time', 'three_g', 'touch_screen', 'wifi'};
trainedClassifier.ClassificationSVM = classificationSVM;
trainedClassifier.About = 'This struct is a trained model exported from Classification Learner R2021b.';
trainedClassifier.HowToPredict = sprintf('To make predictions on a new table, T, use: \n  yfit = c.predictFcn(T) \nreplacing ''c'' with the name of the variable that is this struct, e.g. ''trainedModel''. \n \nThe table, T, must contain the variables returned by: \n  c.RequiredVariables \nVariable formats (e.g. matrix/vector, datatype) must match the original training data. \nAdditional variables are ignored. \n \nFor more information, see <a href="matlab:helpview(fullfile(docroot, ''stats'', ''stats.map''), ''appclassification_exportmodeltoworkspace'')">How to predict using an exported model</a>.');

% Extract predictors and response
% This code processes the data into the right shape for training the
% model.
inputTable = trainingData;
predictorNames = {'battery_power', 'blue', 'clock_speed', 'dual_sim', 'fc', 'four_g', 'int_memory', 'm_dep', 'mobile_wt', 'n_cores', 'pc', 'px_height', 'px_width', 'ram', 'sc_h', 'sc_w', 'talk_time', 'three_g', 'touch_screen', 'wifi'};
predictors = inputTable(:, predictorNames);
response = inputTable.price_range;
isCategoricalPredictor = [false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false, false];

% Perform cross-validation
partitionedModel = crossval(trainedClassifier.ClassificationSVM, 'KFold', 5);

% Compute validation predictions
[validationPredictions, validationScores] = kfoldPredict(partitionedModel);

% Compute validation accuracy
validationAccuracy = 1 - kfoldLoss(partitionedModel, 'LossFun', 'ClassifError');
    end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            %app.ResultLabel.Text = int2str(app.CheckBox_3g.Value)
            
            battery_power=app.BatteryPowerEditField.Value
            blue=app.CheckBox_blue.Value
            if blue ==true
                blue=1;
            elseif(blue== false)
                blue=0
            end
            clock_speed=app.ClockSpeedEditField.Value
            dual_sim=app.CheckBox_dualsim.Value
            if (dual_sim==true)
                dual_sim=1
            elseif(dual_sim==false)
                dual_sim=0
            end
            fc=app.FcEditField.Value
            four_g=app.CheckBox_4g.Value
            if four_g==true
                four_g=1
            elseif four_g==false
                four_g=0
            end
            int_memory=app.InternalMemoryEditField.Value
            m_dep=app.MDepEditField.Value
            mobile_wt=app.MobilewtEditField.Value
            n_cores=app.CoresEditField.Value
            pc=app.PCEditField.Value
            px_height=app.HeightpxEditField.Value
            px_width=app.WidthpxEditField.Value
            ram=app.RamEditField.Value
            sc_h=app.ScreenHeightEditField.Value
            sc_w=app.ScreenWidthEditField.Value
            talk_time=app.TalkTimeEditField.Value
            three_g=app.CheckBox_3g.Value
            if three_g==true
                three_g=1
            elseif three_g==false
                three_g=0
            end
            touch_screen=app.CheckBox_touchScreen.Value
            if touch_screen==true
                touch_screen=1
            elseif touch_screen==false
                touch_screen=0
            end
            wifi=app.CheckBox_wifi.Value
            if wifi==true
                wifi=1
            elseif wifi==false
                wifi=0
            end
            load('deneme.mat', 'deneme')

            
            datasets=table(battery_power,blue,clock_speed,dual_sim,fc,four_g,int_memory,m_dep,mobile_wt,n_cores,pc,px_height,px_width,ram,sc_h,sc_w,talk_time,three_g,touch_screen,wifi)
            %datasets=array2table(datasets)
          %  results=datasets
            yfit=deneme.predictFcn(datasets)
            if yfit==0
                yfit="cop"
            elseif yfit==1
                yfit="normal"
            elseif yfit==2
                yfit="orta"
            elseif yfit==3
                yfit= "pahali"
            end
            %app.ResultLabel.Text=int2str(yfit)
            app.ResultLabel.Text=yfit
            %yfit = trainClassifier(func2(app))
            % app.ResultLabel.Text=yfit
            
            % app.ResultLabel.Text=func2(app)
            %yfit = trainedClassifier.predictFcn(func2(app))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestYourPhoneLabel = uilabel(app.UIFigure);
            app.TestYourPhoneLabel.Text = 'Test Your Phone';
            app.BatteryPowerEditFieldLabel = uilabel(app.UIFigure);
            app.BatteryPowerEditFieldLabel.Tag = 'wifi';
            app.BatteryPowerEditFieldLabel.Text = 'Battery Power';
            app.BatteryPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClockSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.ClockSpeedEditFieldLabel.Text = 'Clock Speed';
            app.ClockSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.FcLabel = uilabel(app.UIFigure);
            app.FcLabel.Text = 'Fc   ';
            app.FcEditField = uieditfield(app.UIFigure, 'numeric');
            app.InternalMemoryEditFieldLabel = uilabel(app.UIFigure);
            app.InternalMemoryEditFieldLabel.Text = 'Internal Memory';
            app.InternalMemoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.MDepEditFieldLabel = uilabel(app.UIFigure);
            app.MDepEditFieldLabel.Text = 'M Dep';
            app.MDepEditField = uieditfield(app.UIFigure, 'numeric');
            app.MobilewtEditFieldLabel = uilabel(app.UIFigure);
            app.MobilewtEditFieldLabel.Text = 'Mobile wt';
            app.MobilewtEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoresEditFieldLabel = uilabel(app.UIFigure);
            app.CoresEditFieldLabel.Text = 'Cores';
            app.CoresEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCEditFieldLabel = uilabel(app.UIFigure);
            app.PCEditFieldLabel.Text = 'PC';
            app.PCEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightpxEditFieldLabel = uilabel(app.UIFigure);
            app.HeightpxEditFieldLabel.Text = 'Height px';
            app.HeightpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthpxEditFieldLabel = uilabel(app.UIFigure);
            app.WidthpxEditFieldLabel.Text = 'Width px';
            app.WidthpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.RamEditFieldLabel = uilabel(app.UIFigure);
            app.RamEditFieldLabel.Text = 'Ram';
            app.RamEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScreenHeightEditFieldLabel = uilabel(app.UIFigure);
            app.ScreenHeightEditFieldLabel.Text = 'Screen Height';
            app.ScreenHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScreenWidthEditFieldLabel = uilabel(app.UIFigure);
            app.ScreenWidthEditFieldLabel.Text = 'Screen Width';
            app.ScreenWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TalkTimeEditFieldLabel = uilabel(app.UIFigure);
            app.TalkTimeEditFieldLabel.Text = 'Talk  Time';
            app.TalkTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Text = 'Blue';
            app.CheckBox_blue = uicheckbox(app.UIFigure);
            app.CheckBox_blue.Text = '';
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = '4G';
            app.CheckBox_dualsim = uicheckbox(app.UIFigure);
            app.CheckBox_dualsim.Text = '';
            app.DualSimLabel = uilabel(app.UIFigure);
            app.DualSimLabel.Text = 'Dual Sim';
            app.CheckBox_4g = uicheckbox(app.UIFigure);
            app.CheckBox_4g.Text = '';
            app.CheckBox_3g = uicheckbox(app.UIFigure);
            app.CheckBox_3g.Text = '';
            app.TouchScreenLabel = uilabel(app.UIFigure);
            app.TouchScreenLabel.Text = 'Touch Screen';
            app.WifiLabel = uilabel(app.UIFigure);
            app.WifiLabel.Text = 'Wifi';
            app.CheckBox_touchScreen = uicheckbox(app.UIFigure);
            app.CheckBox_touchScreen.Text = '';
            app.CheckBox_wifi = uicheckbox(app.UIFigure);
            app.CheckBox_wifi.Text = '';
            app.GLabel_2 = uilabel(app.UIFigure);
            app.GLabel_2.Text = '3G';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'For testing your phone category fill or check all fetures. To take trust result';
            app.YourphoneCategoryisLabel = uilabel(app.UIFigure);
            app.YourphoneCategoryisLabel.Text = 'Your phone Category is :';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end